classdef App_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AWG                    matlab.ui.Figure
        FrequencyKnobLabel     matlab.ui.control.Label
        FrequencyKnob          matlab.ui.control.Knob
        WaveTypeKnobLabel      matlab.ui.control.Label
        WaveTypeKnob           matlab.ui.control.DiscreteKnob
        LoadWaveButton         matlab.ui.control.Button
        SerialConnectionPanel  matlab.ui.container.Panel
        Refresh                matlab.ui.control.Button
        PortLabel_2            matlab.ui.control.Label
        PortList               matlab.ui.control.DropDown
        BCon                   matlab.ui.control.Button
        BDiscon                matlab.ui.control.Button
        Lamp                   matlab.ui.control.Lamp
        FrequencyField         matlab.ui.control.NumericEditField
        AmplitudeField         matlab.ui.control.NumericEditField
        AmplitudeKnobLabel     matlab.ui.control.Label
        AmplitudeKnob          matlab.ui.control.Knob
        PhaseField             matlab.ui.control.NumericEditField
        PhaseKnobLabel         matlab.ui.control.Label
        PhaseKnob              matlab.ui.control.Knob
    end

    
    properties (Access = private)
        TIME_STEP = 0.2
        s % Description
        wavetype='o' % Description
        frequency=0 % Description
        amplitude=0 % Description
        phase=0 % Description
        data='' % Description
        arbwave=zeros(1,1000) % Description
        file % Description
        path 
        t % Description
        arbwave_digits % Description
    end
    
    methods (Access = private)
        
        function sendCommand(app)
            try
                if app.s.BytesToOutput == 0
                    app.data=app.wavetype+sprintf("%03d%02d%03d\r\r", app.frequency, app.amplitude, app.phase);
                    fwrite(app.s, app.data);
                    %app.Terminal.Value=[app.Terminal.Value; app.data];
                end
            end
        end
        
        function readData(app)
%             pause(0.2)
%             while app.s.BytesAvailable > 0
%                 %app.Terminal.Value = [app.Terminal.Value; fscanf(app.s)];
%                 app.Terminal.Items = [app.Terminal.Items fscanf(app.s)]
%                 pause(0.005)
%             end
%             scroll(app.Terminal, 'bottom')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if license('test', 'instr_control_toolbox')
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
        end

        % Button pushed function: Refresh
        function RefreshButtonPushed(app, event)
            if license('test', 'instr_control_toolbox')
                % delete all serial port objets from MATLAB memory
                % (Instrument Control Toolbox required)
                delete(instrfind);
                % looking for available serial ports
                % (Instrument Control Toolbox required)
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
        end

        % Button pushed function: BCon
        function BConButtonPushed(app, event)
            try 
                app.s = serial(app.PortList.Value, 'BaudRate', 115200);
                app.Lamp.Color=[0 1 0];
                fopen(app.s);
                readData(app);
            end
        end

        % Button pushed function: BDiscon
        function BDisconButtonPushed(app, event)
            try  
                stopasync(app.s)
                fclose(app.s);
                app.Lamp.Color=[1 0 0];
            end
        end

        % Value changed function: WaveTypeKnob
        function WaveTypeKnobValueChanged(app, event)
            if(app.WaveTypeKnob.Value=="Off")
                app.wavetype='o';
            elseif(app.WaveTypeKnob.Value=="Sine")
                app.wavetype='s';
            elseif(app.WaveTypeKnob.Value=="Triangle")
                app.wavetype='t';
            elseif(app.WaveTypeKnob.Value=="Square")
                app.wavetype='q';
            elseif(app.WaveTypeKnob.Value=="Arbitrary")
                app.wavetype='a';
            end
            app.txt.Value=app.wavetype+app.WaveTypeKnob.Value;
            sendCommand(app);
            readData(app);
        end

        % Value changed function: FrequencyKnob
        function FrequencyKnobValueChanged(app, event)
            app.frequency=fix(app.FrequencyKnob.Value);
            app.FrequencyField.Value=fix(app.frequency);
            sendCommand(app);
            readData(app);
        end

        % Value changed function: FrequencyField
        function FrequencyFieldValueChanged(app, event)
            try
                app.FrequencyKnob.Value=app.FrequencyField.Value;
                app.frequency=app.FrequencyField.Value;
                sendCommand(app)
                readData(app);
            end
        end

        % Value changed function: AmplitudeKnob
        function AmplitudeKnobValueChanged(app, event)
            app.amplitude=fix(app.AmplitudeKnob.Value*10);
            app.AmplitudeField.Value=round(app.amplitude/10,1);
            sendCommand(app)
            readData(app);
        end

        % Value changed function: AmplitudeField
        function AmplitudeFieldValueChanged(app, event)
            try
                app.AmplitudeKnob.Value=app.AmplitudeField.Value;
                app.amplitude=app.AmplitudeField.Value*10;
                sendCommand(app)
                readData(app);
            end
        end

        % Value changed function: PhaseKnob
        function PhaseKnobValueChanged(app, event)
            app.phase = fix(app.PhaseKnob.Value);
            app.PhaseField.Value = fix(app.PhaseKnob.Value);
            sendCommand(app)
            readData(app);
        end

        % Value changed function: PhaseField
        function PhaseFieldValueChanged(app, event)
            try
                app.PhaseKnob.Value = app.PhaseField.Value;
                app.phase = app.PhaseField.Value;
                sendCommand(app)
                readData(app);
            end
        end

        % Button pushed function: LoadWaveButton
        function LoadWaveButtonPushed(app, event)
            try
                [app.file, app.path] = uigetfile('*.txt');
                app.arbwave = readtable(sprintf("%s%s", app.path, app.file), 'ReadVariableNames', false);
                app.arbwave = table2array(app.arbwave)';
            end
            
            fwrite(app.s, sprintf("l\r"));
            pause(0.005)
            for i=1:1000
                if app.s.BytesToOutput == 0
                    fprintf(app.s, "%04d\r\r", app.arbwave(i));
                    %fwrite(app.s, sprintf("%04d\r\r", app.arbwave(i)));
                    pause(0.005)
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AWG = uifigure('Visible', 'off');
            app.AWG.Name = 'AWG';
            app.FrequencyKnobLabel = uilabel(app.AWG);
            app.FrequencyKnobLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.AWG, 'continuous');
            app.FrequencyKnob.Limits = [0 300];
            app.FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @FrequencyKnobValueChanged, true);
            app.WaveTypeKnobLabel = uilabel(app.AWG);
            app.WaveTypeKnobLabel.Text = 'WaveType';
            app.WaveTypeKnob = uiknob(app.AWG, 'discrete');
            app.WaveTypeKnob.Items = {'Off', 'Sine', 'Triangle', 'Square', 'Arbitrary'};
            app.WaveTypeKnob.ValueChangedFcn = createCallbackFcn(app, @WaveTypeKnobValueChanged, true);
            app.LoadWaveButton = uibutton(app.AWG, 'push');
            app.LoadWaveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWaveButtonPushed, true);
            app.LoadWaveButton.Text = 'Load Wave';
            app.SerialConnectionPanel = uipanel(app.AWG);
            app.SerialConnectionPanel.Title = 'Serial Connection';
            app.Refresh = uibutton(app.SerialConnectionPanel, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.Refresh.Text = 'Refresh';
            app.PortLabel_2 = uilabel(app.SerialConnectionPanel);
            app.PortLabel_2.Text = 'Port';
            app.PortList = uidropdown(app.SerialConnectionPanel);
            app.PortList.Items = {'COM?'};
            app.PortList.Editable = 'on';
            app.PortList.Value = 'COM?';
            app.BCon = uibutton(app.SerialConnectionPanel, 'push');
            app.BCon.ButtonPushedFcn = createCallbackFcn(app, @BConButtonPushed, true);
            app.BCon.Text = 'Connect';
            app.BDiscon = uibutton(app.SerialConnectionPanel, 'push');
            app.BDiscon.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed, true);
            app.BDiscon.Text = 'Disconnect';
            app.Lamp = uilamp(app.SerialConnectionPanel);
            app.FrequencyField = uieditfield(app.AWG, 'numeric');
            app.FrequencyField.ValueChangedFcn = createCallbackFcn(app, @FrequencyFieldValueChanged, true);
            app.AmplitudeField = uieditfield(app.AWG, 'numeric');
            app.AmplitudeField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeFieldValueChanged, true);
            app.AmplitudeKnobLabel = uilabel(app.AWG);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.AWG, 'continuous');
            app.AmplitudeKnob.Limits = [0 3.3];
            app.AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudeKnobValueChanged, true);
            app.PhaseField = uieditfield(app.AWG, 'numeric');
            app.PhaseField.ValueChangedFcn = createCallbackFcn(app, @PhaseFieldValueChanged, true);
            app.PhaseKnobLabel = uilabel(app.AWG);
            app.PhaseKnobLabel.Text = 'Phase';
            app.PhaseKnob = uiknob(app.AWG, 'continuous');
            app.PhaseKnob.Limits = [0 360];
            app.PhaseKnob.ValueChangedFcn = createCallbackFcn(app, @PhaseKnobValueChanged, true);
            % Show the figure after all components are created
            app.AWG.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AWG)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AWG)
        end
    end
end